classdef RiceLeafDiseaseImageRecognitionSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label_9         matlab.ui.control.Label
        LoadButton_5    matlab.ui.control.Button
        Panel           matlab.ui.container.Panel
        LoadButton_1    matlab.ui.control.Button
        ImageAxes       matlab.ui.control.UIAxes
        Step1LampLabel  matlab.ui.control.Label
        Step1Lamp       matlab.ui.control.Lamp
        LoadButton_4    matlab.ui.control.Button
        ImageAxes_2     matlab.ui.control.UIAxes
        Step2LampLabel  matlab.ui.control.Label
        Step2Lamp       matlab.ui.control.Lamp
        Panel_2         matlab.ui.container.Panel
        LoadButton_2    matlab.ui.control.Button
        Step3LampLabel  matlab.ui.control.Label
        Step3Lamp       matlab.ui.control.Lamp
        Label           matlab.ui.control.Label
        TextArea_1      matlab.ui.control.TextArea
        Label_6         matlab.ui.control.Label
        TextArea_2      matlab.ui.control.TextArea
        Label_7         matlab.ui.control.Label
        TextArea_3      matlab.ui.control.TextArea
        Label_8         matlab.ui.control.Label
        TextArea_4      matlab.ui.control.TextArea
        Label_5         matlab.ui.control.Label
        TextArea_5      matlab.ui.control.TextArea
        Panel_3         matlab.ui.container.Panel
        LoadButton_3    matlab.ui.control.Button
        Label_10        matlab.ui.control.Label
        TextArea_6      matlab.ui.control.TextArea
        Label_11        matlab.ui.control.Label
        TextArea_7      matlab.ui.control.TextArea
        Label_15        matlab.ui.control.Label
        TextArea_11     matlab.ui.control.TextArea
        Label_12        matlab.ui.control.Label
        TextArea_8      matlab.ui.control.TextArea
        Label_13        matlab.ui.control.Label
        TextArea_9      matlab.ui.control.TextArea
        Label_14        matlab.ui.control.Label
        TextArea_10     matlab.ui.control.TextArea
        Step4LampLabel  matlab.ui.control.Label
        Step4Lamp       matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        fname % Description
        zongtezheng % Description
        ffname="bingban.jpg"; % Description
        feature % Description
        ff % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
        end

        % Button pushed function: LoadButton_1
        function LoadButton_1Pushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            app.ff=f;
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                app.fname = [p f];
                try
                   im = imread(app.fname);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;                 
                end     
                imagesc(app.ImageAxes,im);  
            end
            app.TextArea_1.Value="";
            app.TextArea_2.Value="";
            app.TextArea_3.Value="";
            app.TextArea_4.Value="";
            app.TextArea_5.Value="";
            app.TextArea_6.Value="";
            app.TextArea_7.Value="";
            app.TextArea_8.Value="";
            app.TextArea_9.Value="";
            app.TextArea_10.Value="";
            app.TextArea_11.Value="";
            
            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
        
            %% create waitbar
            h = waitbar(0,'提取融合特征……');
            %set(h,'Name','提取融合特征……');
            img2=imread(app.ffname);
            %[color,xingzhuang,wenli,CNN_Feature]=getzongtezheng(x);
            %颜色特征
            color=getHsvHist(img2);
            %app.UITable.set('颜色特征',{color'})
            app.TextArea_1.Value=num2str(color); 
            waitbar(1/5);
            
            %形状特征
            xingzhuang=getHuSquare(img2);
            %app.UITable.set('形状特征',{xingzhuang'})
            app.TextArea_2.Value=num2str(xingzhuang);
            waitbar(2/5);
            
            %纹理特征
            wenli=getwenli2(img2);
            %app.UITable.set('纹理特征',{wenli'})
            app.TextArea_3.Value=num2str(wenli);
            waitbar(3/5);
            
            %卷积特征
            CNN_Feature=getCNNFeature(img2);
            %app.UITable.set('卷积特征',{CNN_Feature'})
            app.TextArea_4.Value=num2str(CNN_Feature);
            waitbar(4/5);
            
            %总特征
            app.zongtezheng=[color,xingzhuang,wenli,CNN_Feature];
            app.zongtezheng=zscore(app.zongtezheng);
            
            %归一化处理
            %A=mapminmax(app.zongtezheng);
            %CCA融合
%             cst_Test=A(1,1:44);
%             CNNTest=A(1,45:60);
%             testX=cst_Test;
%             testY=CNNTest;
            testX=[color,wenli];
            testY=xingzhuang;
            load meanX;
            load meanY;
            load Wxpca.mat;
            load Wypca.mat;
            load Wxcca.mat;
            load Wycca.mat;
            
            %% Center the variables
            testX  = bsxfun(@minus, testX,  meanX);
            testY  = bsxfun(@minus, testY,  meanY);
            %% Dimensionality reduction using PCA for the first data X
            testX = testX * Wxpca;
            %% Dimensionality reduction using PCA for the second data Y
            testY = testY * Wypca;
            %% Fusion using Canonical Correlation Analysis (CCA)
            testXcca = testX * Wxcca;
            testYcca = testY * Wycca;
            % Fusion by concatenation
            testZ  = [testXcca, testYcca];
            result = testZ;
            % 分行标准化处理 
%             for m=1:size(result,1)
%               result(m,:)=zscore(result(m,:));
%             end
            waitbar(5/5);
            
            close(h);%close the bar
            
            app.TextArea_5.Value=num2str(result);
            app.feature=result;


        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
           
            %load net;
            load 'SVM_baiyeku7.mat' ;
            load 'SVM_daowenbing7.mat';
            load 'SVM_humaban7.mat';
            %%分类预测
            
%             %BP
%             y_test=sim(net,test_data');
%             y_label=zeros(size(test_data,1),1);
%             %精度计算
%             for i=1:test_num
%                 [value,place]=max(y_test(:,i));
%                 y_label(i,:)=place;
%             end
            
            %SVM
            %数据标准化处理
            
            temp=app.zongtezheng(1:44);
            temp=zscore(temp);
            
            [predict_baiyeku,score_baiyeku] = predict(model_baiyeku,app.feature(1:2));
            [predict_daowenbing,score_daowenbing] = predict( model_daowenbing,app.feature(1:2));
            [predict_humaban,score_humaban] = predict(model_humaban,app.feature(1:2));
            
            % 求出测试样本在3个模型中预测为“正”得分的最大值，作为该测试样本的最终预测标签
            score = [score_baiyeku(:,2),score_daowenbing(:,2),score_humaban(:,2)];
            [m,p] = max(score);

            switch p
                case 1
                    app.TextArea_6.Value="白叶枯 ";
                    app.TextArea_7.Value="水稻白叶枯病原菌属真细菌目，假单胞菌科，黄单胞菌属，为短杆状，一根极生鞭毛，不形成芽孢。病原菌形态特征：细菌杆状有单根极鞭毛，格兰氏染色反应阴性。菌落圆形，表面光滑有光泽，蜡黄色。";
                    app.TextArea_8.Value="在空气潮湿时，病叶上的新鲜病斑上，有时甚至在未表现病斑的叶缘上分泌出湿浊状的水珠或蜜黄色菌胶，干涸后结成硬粒，容易脱落。在籼稻上的白叶枯病斑多半呈黄色或黄绿色，在粳稻上则为灰绿至灰白色。在感病品种上，初起病斑呈开水烫过的灰绿色，很快向下发展为长条状黄白色,在我国南方稻区一些高感品种上发生凋萎型白叶枯病，主要发生在秧苗生长后期或本田移植后1～4星期内，主要特征为失水、青枯、卷曲、凋萎，形似螟害枯心。诊断方法，将枯心株拔起，切断茎基部，用手挤压，如切口处溢出涕状黄白色菌脓，即为本病。如为螟害枯心，可见有虫蛀眼。";
                    app.TextArea_9.Value="白叶枯病菌主要在稻种、稻草和稻桩上越冬，据江苏研究，重病田稻桩附近土壤中的细菌也可越年传病。播种病谷，病菌可通过幼苗的根和芽鞘侵入。病稻草和稻桩上的病菌，遇到雨水就渗入水流中，秧苗接触带菌水，病菌从水孔、伤口侵入稻体。用病稻草催芽，覆盖秧苗、扎秧把等有利病害传播。早、中稻秧田期由于温度低，菌量较少，一般看不到症状，直到孕穗前后才暴发出来。病斑上的溢脓，可借风、雨、露水和叶片接触等进行再侵染。";
                    app.TextArea_10.Value="①选用抗病品种：发生过白叶枯病的田块和低洼易涝田都要种植抗病品种。②种子消毒：用强氯精浸种或80%“402”浸种，浸种方法同稻瘟病。③培育无病壮秧：选好秧田位置，加强灌溉水管理，防止淹苗。在三叶一心期和移栽前施药预防。亩用25%叶枯宁（又叫川化018）或叶青双可湿性粉剂100克兑水喷雾。④加强水肥管理：平整稻田，防止串灌、漫灌传播病害；适时适度晒田，施足底肥，多施P、K肥，不要过量过迟追施氮肥。⑤大田施药保护：水稻拔节后，对感病品种要即早检查，如发现发病中心，应立即施药防治；感病品种稻田在大风雨后要施药。发病初期，对稻苗喷20%氟硅唑咪鲜胺800～1000倍液，注意观察变化视病情可隔5-7天施一次。发病中期，20%氟硅唑咪鲜胺1000倍液+2%氨基寡糖素1200倍液， 5—7天用药1次，连用2-3次。";
                    app.TextArea_11.Value="水稻白叶枯病在欧洲、非洲、南美、美国、澳大利亚、亚洲都有发生；而以日本、印度、我国发生较重。我国主要华东、华中、华南稻区发生重；西北、西南、华北和东北部分稻区也有分布，西藏也有发现。只有新疆、甘肃未见报道。";
                case 2
                    app.TextArea_6.Value="稻瘟病 ";
                    app.TextArea_7.Value="稻瘟病是水稻重要病害之一，可引起大幅度减产，严重时减产40%～50%，甚至颗粒无收。世界各稻区均匀发生。本病在各地均有发生，其中以叶部、节部发生为多，发生后可造成不同程度减产，尤其穗颈瘟或节瘟发生早而重，可造成白穗以致绝产。近年来，广东稻瘟病年发生面积不少于50万亩，而且出现逐年增加趋势，局部大爆发并不少见，目前，稻瘟病可能发生在省域内的任何年头、任何季节。";
                    app.TextArea_8.Value="主要危害叶片、茎秆、穗部。因为害时期、部位不同分为苗瘟、叶瘟、节瘟、穗颈瘟、谷粒瘟。苗瘟发生于三叶前，由种子带菌所致。病苗基部灰黑，上部变褐，卷缩而死，湿度较大时病部产生大量灰黑色霉层，即病原菌分生孢子梗和分生孢子。叶瘟在整个生育期都能发生。分蘖至拔节期为害较重。又名稻热病，俗称火烧瘟、嗑头瘟等。田间鉴别症状；危害水稻各部分，在水稻整个生育期都发生。秧苗；发病后变成黄褐色而枯死，不形成明显病斑，潮湿时，可长出青灰色霉。在我国南、北方稻区都有不同程度发生，流行年份一般减产10-20%，严重的减产达40-50%，在水稻秧苗期和分蘖期发病，可使叶片大量枯死，严重时全田呈火烧状，有些稻株虽不枯死，但抽出的新叶不易伸长，植株萎缩不抽穗或抽出短小的穗，孕穗抽穗期发病、节瘟、穗颈瘟严重发生，可造成大量白穗或半白穗。";
                    app.TextArea_9.Value="稻瘟病是真菌寄生引起，青灰色霉即是病菌的分生孢子，病害的扩展靠分生孢子在空气中传播。病菌发育最适温度为25℃～28℃，高湿有利分生孢子形成飞散和萌发，而高湿度持续达一昼夜以上，则有利于病害发生流行。";
                    app.TextArea_10.Value="根据当地预报及时检查田间症状。合理施肥管水，底肥足，追肥早，巧补穗肥，多施农家肥，节氮增施磷钾肥，防止偏施迟施氮肥，以增强植株抗病力，减轻发病:①选用排灌方便的田块，不用带菌稻草作苗床的覆盖物和扎秧草②不种植感病品种，选用抗病、无病、包衣的种子，如未包衣则用拌种剂或浸种剂灭菌；因地制宜选用2-3个适合当地抗病品种。③用无病土做苗床营养土，用药土做播种后的覆盖土，④向大田移栽前，喷施一次除虫灭菌的混合药，⑤肥料管理：提倡施用酵素菌沤制的或充分腐熟的农家肥，采取测土配方技术，和早促、中控、晚保的方针，重施基肥，科学施用氮肥，增施磷、钾肥。加强田间管理，培育壮苗，增强植株抗病力，有利于减轻病害。⑥水份管理：浅水勤灌，防止串灌；烤田适中。⑦加强栽培管理，催芽不宜过长，拔秧要尽可能避免损根。做到“五不插”：即不插隔夜秧，不插老龄秧，不插深泥秧，不插烈日秧，不插冷水浸的秧。⑧发现病株，及时拔除烧毁或高温沤肥。";
                    app.TextArea_11.Value="近年来，广东稻瘟病年发生面积不少于50万亩，而且出现逐年增加趋势，局部大爆发并不少见，目前，稻瘟病可能发生在省域内的任何年头、任何季节。";
                case 3
                    app.TextArea_6.Value="胡麻斑 ";
                    app.TextArea_7.Value="水稻胡麻斑病中国各稻区均有发生，主要危害水稻。从秧苗期至收获期均可发病，稻株地上部均可受害，以叶片为多。";
                    app.TextArea_8.Value="种子芽期受害，芽鞘变褐，芽未抽出，子叶枯死。苗期叶片、叶鞘发病多为椭圆病斑，如胡麻粒大小，暗褐色，有时病斑扩大连片成条形，病斑多时秧苗枯死。成株叶片染病初为褐色小点，渐扩大为椭圆斑，如芝麻粒大小，病斑中央褐色至灰白，边缘褐色，周围有深浅不同的黄色晕圈，严重时连成不规则大斑。，病叶由叶尖向内干枯，潮湿时，死苗上产生黑色霉状物（病菌分生孢子梗和分生孢子）。叶鞘上染病病斑初椭圆形，暗褐色，边缘淡褐色，水渍状，后变为中心灰褐色的不规则大斑。穗颈和枝梗发病受害部暗褐色，造成穗枯。谷粒染病早期受害的谷粒灰黑色扩至全粒造成秕谷。后期受害病斑小，边缘不明显。病重谷粒质脆易碎。气候湿润时，上述病部长出黑色绒状霉层，即病原菌分生孢子梗和分生孢子。";
                    app.TextArea_9.Value="高温高湿、有雾露存在时发病重。酸性土壤，砂质土，缺磷少钾时易发病。旱秧田发病重。菌丝生长温限5～35℃，24～30℃最适，分生孢子形成温限8～33℃，30℃最适。萌发温限2～40℃，24～30℃最适。孢子萌发须有水滴存在，相对湿度大于92%。饱和湿度下25～28℃，4小时就可侵入寄主。";
                    app.TextArea_10.Value="①深耕灭茬，压低菌源。病稻草要及时处理销毁。②选在无病田留种或种子消毒。③增施腐熟堆肥做基肥，及时追肥，增加磷钾肥，特别是钾肥的施用可提高植株抗病力。酸性土注意排水，适当施用石灰。要浅灌勤灌，避免长期水淹造成通气不良。④药剂防治参见稻瘟病。";
                    app.TextArea_11.Value="中国各稻区均有发生。";
            end
        end

        % Button pushed function: LoadButton_4
        function LoadButton_4Pushed(app, event)
           
            
            ima_end=bingbantiqu(app.fname) ;
            imwrite(ima_end,app.ffname);
            
            %上传图窗
            imagesc(app.ImageAxes_2,ima_end);  
            
%             img=imread(app.fname); 
%             %双边滤波
%             f = img;
%  
%             % 设置参数
%             r = 5;% 滤波半径
%             a = 3;% 全局方差
%             b = 0.1;% 局部方差
%              
%             % 判断二维图还是三维图
%             % if ismatrix(f)
%             %     g = bfilt_gray(f,r,a,b);
%             % else
%                 g = bfilt_rgb(f,r,a,b);
%             % end
%              
%             % 显示
%             
%              
%              
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%彩色图双边滤波%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             function g = bfilt_rgb(f,r,a,b)
%             % f灰度图；r滤波半径；a全局方差；b局部方差
%             [x,y] = meshgrid(-r:r);
%             w1 = exp(-(x.^2+y.^2)/(2*a^2));
%             f = tofloat(f);%f = im2double(f);
%              
%             h = waitbar(0,'Applying bilateral filter...');
%             set(h,'Name','Bilateral Filter Progress');
%              
%             fr = f(:,:,1);
%             fg = f(:,:,2);
%             fb = f(:,:,3);
%             [m,n] = size(fr);
%             fr_temp = padarray(fr,[r r],'symmetric');
%             fg_temp = padarray(fg,[r r],'symmetric');
%             fb_temp = padarray(fb,[r r],'symmetric');
%             [gr,gg,gb] = deal(zeros(size(fr)));
%              
%              
%             for i = r+1:m+r
%                 for j = r+1:n+r
%                     temp1 = fr_temp(i-r:i+r,j-r:j+r);
%                     temp2 = fg_temp(i-r:i+r,j-r:j+r);
%                     temp3 = fb_temp(i-r:i+r,j-r:j+r);
%                     dr = temp1 - fr_temp(i,j);
%                     dg = temp2 - fg_temp(i,j);
%                     db = temp3 - fb_temp(i,j);
%                     w2 = exp(-(dr.^2+dg.^2+db.^2)/(2*b^2));
%                     w = w1.*w2;
%                     gr(i-r,j-r) = sum(sum(temp1.*w))/sum(w(:));
%                     gg(i-r,j-r) = sum(sum(temp2.*w))/sum(w(:));
%                     gb(i-r,j-r) = sum(sum(temp3.*w))/sum(w(:));
%                 end
%                 waitbar((i-r)/n);
%             end
%             g = cat(3,gr,gg,gb);
%             % g = revertclass(g);
%             end
%             
%             function [out,revertclass]=tofloat(in)
%             identity=@(x) x;
%             tosingle=@im2single;
%              
%             table={'uint8',tosingle,@im2uint8
%             'uint16',tosingle,@im2uint16
%             'int16',tosingle,@im2int16
%             'logical',tosingle,@logical
%             'double',identity,identity
%             'single',identity,identity};
%              
%             classIndex=find(strcmp(class(in),table(:,1)));
%              
%             if isempty(classIndex)
%                 error('unsupported input immage class.');
%             end
%              
%             out=table{classIndex,2}(in);
%             revertclass=table{classIndex,3}; 
%             end
%             
%             %病斑分割
%             ima_orig= img;
%             ima_gray=rgb2gray(ima_orig);
%             ima_line=imadjust(ima_orig,[0.3,0.8],[0.2,0.9],1.7);%灰度线性变换
%             %imadjust（f，[low_in  high_in],[low_out,high_out],gamma）。gamma小于1，则图像整体变亮，大于1，整体变暗。改变gamma值可得到不同效果的处理结果（前面的参数不需要修改）
%             ima_im2bw=im2bw(ima_line,graythresh(ima_line));%Otsu
%             height=size(ima_orig,1);%读取原图的长宽
%             width=size(ima_orig,2);
%             ima_sizeSame=imresize(ima_im2bw,[height,width]);%将二值图剪裁至和原图大小一致
%             ima_end=change_color(ima_sizeSame,ima_orig);%将二值图变为彩色图
%                
%             %上传图窗
%             app.ImageAxes_2.Visible="on";
%             imagesc(app.ImageAxes_2,ima_end);  
%             app.img_afterSegmentation=ima_end;
        end

        % Button pushed function: LoadButton_5
        function LoadButton_5Pushed(app, event)
            LoadButton_1Pushed(app, event);
            LoadButton_4Pushed(app, event);
            LoadButton_2Pushed(app, event);
            LoadButton_3Pushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'RiceLeafDiseaseImageRecoginationSystem';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '基于集成特征表示的稻叶病图像识别系统';
            app.LoadButton_5 = uibutton(app.UIFigure, 'push');
            app.LoadButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_5Pushed, true);
            app.LoadButton_5.Text = '一键运行';
            app.Panel = uipanel(app.UIFigure);
            app.LoadButton_1 = uibutton(app.Panel, 'push');
            app.LoadButton_1.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_1Pushed, true);
            app.LoadButton_1.Text = '上传图像';
            app.ImageAxes = uiaxes(app.Panel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.Step1LampLabel = uilabel(app.Panel);
            app.Step1LampLabel.Text = 'Step1';
            app.Step1Lamp = uilamp(app.Panel);
            app.LoadButton_4 = uibutton(app.Panel, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_4Pushed, true);
            app.LoadButton_4.Text = '分割病斑';
            app.ImageAxes_2 = uiaxes(app.Panel);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.Step2LampLabel = uilabel(app.Panel);
            app.Step2LampLabel.Text = 'Step2';
            app.Step2Lamp = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadButton_2 = uibutton(app.Panel_2, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = '抽取融合特征';
            app.Step3LampLabel = uilabel(app.Panel_2);
            app.Step3LampLabel.Text = 'Step3';
            app.Step3Lamp = uilamp(app.Panel_2);
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '颜色特征';
            app.TextArea_1 = uitextarea(app.Panel_2);
            app.TextArea_1.Editable = 'off';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '形状特征';
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.TextArea_2.Editable = 'off';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '纹理特征';
            app.TextArea_3 = uitextarea(app.Panel_2);
            app.TextArea_3.Editable = 'off';
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '卷积特征';
            app.TextArea_4 = uitextarea(app.Panel_2);
            app.TextArea_4.Editable = 'off';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '融合特征';
            app.TextArea_5 = uitextarea(app.Panel_2);
            app.TextArea_5.Editable = 'off';
            app.Panel_3 = uipanel(app.UIFigure);
            app.LoadButton_3 = uibutton(app.Panel_3, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = '识别分类';
            app.Label_10 = uilabel(app.Panel_3);
            app.Label_10.Text = '识别结果';
            app.TextArea_6 = uitextarea(app.Panel_3);
            app.TextArea_6.Editable = 'off';
            app.Label_11 = uilabel(app.Panel_3);
            app.Label_11.Text = '简介';
            app.TextArea_7 = uitextarea(app.Panel_3);
            app.TextArea_7.Editable = 'off';
            app.Label_15 = uilabel(app.Panel_3);
            app.Label_15.Text = '主要分布区域';
            app.TextArea_11 = uitextarea(app.Panel_3);
            app.TextArea_11.Editable = 'off';
            app.Label_12 = uilabel(app.Panel_3);
            app.Label_12.Text = '危害症状';
            app.TextArea_8 = uitextarea(app.Panel_3);
            app.TextArea_8.Editable = 'off';
            app.Label_13 = uilabel(app.Panel_3);
            app.Label_13.Text = '发病特点';
            app.TextArea_9 = uitextarea(app.Panel_3);
            app.TextArea_9.Editable = 'off';
            app.Label_14 = uilabel(app.Panel_3);
            app.Label_14.Text = '防治办法';
            app.TextArea_10 = uitextarea(app.Panel_3);
            app.TextArea_10.Editable = 'off';
            app.Step4LampLabel = uilabel(app.Panel_3);
            app.Step4LampLabel.Text = 'Step4';
            app.Step4Lamp = uilamp(app.Panel_3);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RiceLeafDiseaseImageRecognitionSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end